classdef Fator_de_Potencia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Grafico                         matlab.ui.control.UIAxes
        AmplitudedaCorrenteReativaCorrenteEficazLabel  matlab.ui.control.Label
        Irms                            matlab.ui.control.NumericEditField
        AmplitudedaTensoReativaTensoEficazLabel  matlab.ui.control.Label
        Vrms                            matlab.ui.control.NumericEditField
        FasedaCorrenteLabel             matlab.ui.control.Label
        Fase                            matlab.ui.control.Slider
        FrequnciaHzEditFieldLabel       matlab.ui.control.Label
        Frequencia                      matlab.ui.control.NumericEditField
        SituaodaCorrenteEditFieldLabel  matlab.ui.control.Label
        SituacaoCorrente                matlab.ui.control.EditField
        ComponenteButtonGroup           matlab.ui.container.ButtonGroup
        ResistnciaPuraButton            matlab.ui.control.RadioButton
        IndutorPuroButton               matlab.ui.control.RadioButton
        CapacitorPuroButton             matlab.ui.control.RadioButton
        ResistorCapacitorButton         matlab.ui.control.RadioButton
        ResistorIndutorMotorButton      matlab.ui.control.RadioButton
        TipodaCorrenteLabel             matlab.ui.control.Label
        TipoCorrente                    matlab.ui.control.EditField
        PotnciaMdiaouAtivaWEditFieldLabel  matlab.ui.control.Label
        PotenciaAtiva                   matlab.ui.control.NumericEditField
        TextArea                        matlab.ui.control.TextArea
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        PotnciaReativaVArEditFieldLabel  matlab.ui.control.Label
        PotenciaReativa                 matlab.ui.control.NumericEditField
        PotnciaAparenteVAEditFieldLabel  matlab.ui.control.Label
        PotenciaAparente                matlab.ui.control.NumericEditField
        FatordePotnciaEditFieldLabel    matlab.ui.control.Label
        FatorDePotencia                 matlab.ui.control.NumericEditField
        PerdasJouleLabel                matlab.ui.control.Label
        PerdasJoulicas                  matlab.ui.control.NumericEditField
        ResistnciadosCondutoresOhmsLabel  matlab.ui.control.Label
        Resistencia                     matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Fase
        function CalcularButtonPushed(app, event)
            f = app.Frequencia.Value;
            R = app.Resistencia.Value;
            w = 2*pi*f;
            Vrms = app.Vrms.Value;
            t = linspace(0,0.1,100e3);
            v = sqrt(2)*Vrms .*sin(w*t);
            Irms = app.Irms.Value;
            fase = app.Fase.Value;
            i = sqrt(2)*Irms .*sin(w*t + fase*pi/180);
            p = v.*i;
            plot(app.Grafico, t,v, t,i, t,p);
            if fase<0
                app.SituacaoCorrente.Value = "Atrasada";
                if fase == -90
                    app.TipoCorrente.Value = "Indutiva Pura";
                    app.Grafico.Title.String = "Gráfico de U, I e P com Indutor Puro";
                    app.ComponenteButtonGroup.SelectedObject = app.IndutorPuroButton;
                else
                    app.TipoCorrente.Value = "Resistiva-Indutiva";
                    app.Grafico.Title.String = "Gráfico de U, I e P com Resistor-Indutor (Motor de Indução)";
                    app.ComponenteButtonGroup.SelectedObject = app.ResistorIndutorMotorButton;
                end
            elseif fase>0
                app.SituacaoCorrente.Value = "Adiantada";
                if fase == 90
                    app.TipoCorrente.Value = "Capacitiva Pura";
                    app.Grafico.Title.String = "Gráfico de U, I e P com Capacitor Puro";
                    app.ComponenteButtonGroup.SelectedObject = app.CapacitorPuroButton;
                else
                    app.TipoCorrente.Value = "Resistiva-Capacitiva";
                    app.Grafico.Title.String = "Gráfico de U, I e P com Resistor-Capacitor (Motor Síncrono)";
                    app.ComponenteButtonGroup.SelectedObject = app.ResistorCapacitorButton;
                end
            else
                app.SituacaoCorrente.Value = "Em Fase";
                app.TipoCorrente.Value = "Resistiva Pura";
                app.Grafico.Title.String = "Gráfico de U, I e P com Resistor Puro (Forno Resistivo)";
                app.ComponenteButtonGroup.SelectedObject = app.ResistnciaPuraButton;
            end
            if (app.TipoCorrente.Value=="Capacitiva Pura" || app.TipoCorrente.Value=="Indutiva Pura")
                Q = Vrms*Irms*sin(-fase*pi/180);
                app.PotenciaReativa.Value = Q;
                S = Vrms*Irms;
                app.PotenciaAparente.Value = S;
                P = 0;
                app.PotenciaAtiva.Value = P;
                PJ = R*(Irms^2); % Perdas Joulicas
                app.PerdasJoulicas.Value = PJ;
            elseif app.TipoCorrente.Value=="Resistiva Pura"
                Q = 0;
                app.PotenciaReativa.Value = Q;
                P = Vrms*Irms;
                app.PotenciaAtiva.Value = P;
                S = Vrms*Irms;
                app.PotenciaAparente.Value = S;
                PJ = R*(Irms^2);
                app.PerdasJoulicas.Value = PJ;
            else
                Q = Vrms*Irms*sin(-fase*pi/180);
                app.PotenciaReativa.Value = Q;
                S = Vrms*Irms;
                app.PotenciaAparente.Value = S;
                P = Vrms*Irms*cos(-fase*pi/180); % Potência Média ou Ativa
                app.PotenciaAtiva.Value = P;
                PJ = R*(Irms^2); % Perdas Joulicas
                app.PerdasJoulicas.Value = PJ;
            end
            FP = P/S; % Fator de Potência
            app.FatorDePotencia.Value = FP;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafico = uiaxes(app.UIFigure);
            title(app.Grafico, 'Gráfico de Tensão, Corrente e Potência')
            xlabel(app.Grafico, 'Tempo')
            ylabel(app.Grafico, 'Amplitudes')
            app.AmplitudedaCorrenteReativaCorrenteEficazLabel = uilabel(app.UIFigure);
            app.AmplitudedaCorrenteReativaCorrenteEficazLabel.Text = {'Amplitude da Corrente Reativa'; '(Corrente Eficaz)'};
            app.Irms = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudedaTensoReativaTensoEficazLabel = uilabel(app.UIFigure);
            app.AmplitudedaTensoReativaTensoEficazLabel.Text = {'Amplitude da Tensão Reativa'; '(Tensão Eficaz)'};
            app.Vrms = uieditfield(app.UIFigure, 'numeric');
            app.FasedaCorrenteLabel = uilabel(app.UIFigure);
            app.FasedaCorrenteLabel.Text = 'Fase da Corrente';
            app.Fase = uislider(app.UIFigure);
            app.Fase.Limits = [-90 90];
            app.Fase.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.FrequnciaHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequnciaHzEditFieldLabel.Text = 'Frequência (Hz)';
            app.Frequencia = uieditfield(app.UIFigure, 'numeric');
            app.SituaodaCorrenteEditFieldLabel = uilabel(app.UIFigure);
            app.SituaodaCorrenteEditFieldLabel.Text = 'Situação da Corrente';
            app.SituacaoCorrente = uieditfield(app.UIFigure, 'text');
            app.SituacaoCorrente.Enable = 'off';
            app.ComponenteButtonGroup = uibuttongroup(app.UIFigure);
            app.ComponenteButtonGroup.Title = 'Componente';
            app.ResistnciaPuraButton = uiradiobutton(app.ComponenteButtonGroup);
            app.ResistnciaPuraButton.Enable = 'off';
            app.ResistnciaPuraButton.Text = 'Resistência Pura';
            app.ResistnciaPuraButton.Value = true;
            app.IndutorPuroButton = uiradiobutton(app.ComponenteButtonGroup);
            app.IndutorPuroButton.Enable = 'off';
            app.IndutorPuroButton.Text = 'Indutor Puro';
            app.CapacitorPuroButton = uiradiobutton(app.ComponenteButtonGroup);
            app.CapacitorPuroButton.Enable = 'off';
            app.CapacitorPuroButton.Text = 'Capacitor Puro';
            app.ResistorCapacitorButton = uiradiobutton(app.ComponenteButtonGroup);
            app.ResistorCapacitorButton.Enable = 'off';
            app.ResistorCapacitorButton.Text = 'Resistor-Capacitor';
            app.ResistorIndutorMotorButton = uiradiobutton(app.ComponenteButtonGroup);
            app.ResistorIndutorMotorButton.Enable = 'off';
            app.ResistorIndutorMotorButton.Text = 'Resistor-Indutor (Motor)';
            app.TipodaCorrenteLabel = uilabel(app.UIFigure);
            app.TipodaCorrenteLabel.Text = 'Tipo da Corrente';
            app.TipoCorrente = uieditfield(app.UIFigure, 'text');
            app.TipoCorrente.Enable = 'off';
            app.PotnciaMdiaouAtivaWEditFieldLabel = uilabel(app.UIFigure);
            app.PotnciaMdiaouAtivaWEditFieldLabel.Enable = 'off';
            app.PotnciaMdiaouAtivaWEditFieldLabel.Text = 'Potência Média ou Ativa (W)';
            app.PotenciaAtiva = uieditfield(app.UIFigure, 'numeric');
            app.PotenciaAtiva.Enable = 'off';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'ESTUDO GRÁFICO DE FASORES, POTÊNCIAS MÉDIAS E VALORES EFICAZES EM CORRENTE ALTERNADA'; 'Por Lucas Gonçalves de Oliveira Martins'};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.IndutorPuroButton.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.PotnciaReativaVArEditFieldLabel = uilabel(app.UIFigure);
            app.PotnciaReativaVArEditFieldLabel.Enable = 'off';
            app.PotnciaReativaVArEditFieldLabel.Text = 'Potência Reativa (VAr)';
            app.PotenciaReativa = uieditfield(app.UIFigure, 'numeric');
            app.PotenciaReativa.Enable = 'off';
            app.PotnciaAparenteVAEditFieldLabel = uilabel(app.UIFigure);
            app.PotnciaAparenteVAEditFieldLabel.Enable = 'off';
            app.PotnciaAparenteVAEditFieldLabel.Text = 'Potência Aparente (VA)';
            app.PotenciaAparente = uieditfield(app.UIFigure, 'numeric');
            app.PotenciaAparente.Enable = 'off';
            app.FatordePotnciaEditFieldLabel = uilabel(app.UIFigure);
            app.FatordePotnciaEditFieldLabel.Enable = 'off';
            app.FatordePotnciaEditFieldLabel.Text = 'Fator de Potência (%)';
            app.FatorDePotencia = uieditfield(app.UIFigure, 'numeric');
            app.FatorDePotencia.Enable = 'off';
            app.PerdasJouleLabel = uilabel(app.UIFigure);
            app.PerdasJouleLabel.Enable = 'off';
            app.PerdasJouleLabel.Text = 'Perdas Joule';
            app.PerdasJoulicas = uieditfield(app.UIFigure, 'numeric');
            app.PerdasJoulicas.Enable = 'off';
            app.ResistnciadosCondutoresOhmsLabel = uilabel(app.UIFigure);
            app.ResistnciadosCondutoresOhmsLabel.Text = {'Resistência dos'; 'Condutores (Ohms)'};
            app.Resistencia = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fator_de_Potencia

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end